classdef proj < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        EncodingTab                 matlab.ui.container.Tab
        EncoderLabel                matlab.ui.control.Label
        TexttoEncodeEditFieldLabel  matlab.ui.control.Label
        TexttoEncodeEditField       matlab.ui.control.EditField
        LoadImageButton             matlab.ui.control.Button
        Image                       matlab.ui.control.Image
        Image2                      matlab.ui.control.Image
        OriginalImageLabel          matlab.ui.control.Label
        AlteredImageLabel           matlab.ui.control.Label
        Image3                      matlab.ui.control.Image
        EncodeButton                matlab.ui.control.Button
        HeightEditFieldLabel        matlab.ui.control.Label
        HeightEditField             matlab.ui.control.NumericEditField
        WidthEditFieldLabel         matlab.ui.control.Label
        WidthEditField              matlab.ui.control.NumericEditField
        ResizeValuesLabel           matlab.ui.control.Label
        DecodingTab                 matlab.ui.container.Tab
        DecoderLabel                matlab.ui.control.Label
        LoadImageButton_2           matlab.ui.control.Button
        Image_2                     matlab.ui.control.Image
        ImageLabel                  matlab.ui.control.Label
        DecodedMessageLabel         matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        Image3_2                    matlab.ui.control.Image
        DecodeButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        messageProp = 'Sample Text'; % stores message
        fileProp; % stores load file name
        pathProp; % stores load file path
        idxProp; % stores extension index
        extProp; % stores extension list
        imProp; % stores original image
        imoutProp; % stores final output image
        hProp = 256; % stores height
        wProp = 256; % stores width
        filepathoutProp; %stores output file path (including name)
        decodedmessageProp % stores the decoded message from the file
                
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            validext = {'*.jpg'};
            [file, path, idx] = uigetfile(validext);
            ext = validext{idx};
            ext = ext(2:end);
            
            app.fileProp = file; app.pathProp = path; 
            app.idxProp = idx; app.extProp = ext;
            
            filepath = string(path) + string(file);
            
            im = imread(filepath);
            % im = imread('lab12stuff/color.jpg');
            
            app.imProp = im;
          
            
            h = app.hProp; w = app.wProp;
            app.hProp = h; app.wProp = w;
            
            im = rgb2gray(im);
            im=imresize(im, [h w]);
            imageshown = cat(3, im, cat(3, im, im));
            app.Image.ImageSource = imageshown;
            
            app.imProp = im;
        end

        % Value changed function: TexttoEncodeEditField
        function TexttoEncodeEditFieldValueChanged(app, event)
            value = app.TexttoEncodeEditField.Value;
            if (length(value) > 31)
                app.TexttoEncodeEditField.Value = value(1:31);
                value = app.TexttoEncodeEditField.Value;
            end
            
            app.messageProp = value;
        end

        % Button pushed function: EncodeButton
        function EncodeButtonPushed(app, event)
            message = app.messageProp;
            ascii = uint8(message);
            bin = dec2bin(ascii, 8)';  
            bin = bin(:);
            l = length(bin);
            
            if (l ~= 0)
                im = app.imProp;
                h = app.hProp; w = app.wProp;
                imout = im;
                
                bin_num = str2num(bin);
                
                count = 0;
                
                for i = 1:h 
                    for j = 1:w 
                        if(count < l) 
                            leastbit = mod(double(im(i, j)), 2); 
                            added = double(xor(leastbit, bin_num(count+1))); 
                            imout(i, j) = im(i, j)+added;  
                            count = count + 1; 
                        end
                    end
                end
                
                imout(h,w) = l;
                app.imoutProp = imout;
                
                imageshown2 = cat(3, imout, cat(3, imout, imout));
                app.Image2.ImageSource = imageshown2;
                
                path = app.pathProp; file = app.fileProp; ext = app.extProp;
                
                filepathout = string(path) + string(file(1:end-length(ext))) + '_output' + ext;
                imwrite(imout, filepathout, 'Mode', 'lossless');
            end   
            
            
        end

        % Button pushed function: LoadImageButton_2
        function LoadImageButton_2Pushed(app, event)
            validext = {'*.jpg'};
            [file, path, idx] = uigetfile(validext);
            
            app.fileProp = file; app.pathProp = path; 
            
            filepath = string(path) + string(file);
            
            im = imread(filepath);
            % im = imread('lab12stuff/color.jpg');
            
            app.imProp = im;
          
            
            h = size(im, 1); w = size(im, 2);
            app.hProp = h; app.wProp = w;
            
            imageshown3 = cat(3, im, cat(3, im, im));
            app.Image_2.ImageSource = imageshown3;
            
            app.imProp = im;
        end

        % Button pushed function: DecodeButton
        function DecodeButtonPushed(app, event)
            
            im = app.imProp;
            h = app.hProp; w = app.wProp;
            
            l = im(h,w);
                
            count = 0;
            for i = 1:h 
                for j = 1:w 
                    if (count < l) 
                        bitarr(count+1, 1) = mod(double(im(i, j)), 2); 
                        count = count + 1; 
                    end
                end
            end
            
            
            binvals = [128 64 32 16 8 4 2 1]; 
            binmat = reshape(bitarr, 8, l/8);
            
            textString = char(binvals*binmat);  
            
            app.decodedmessageProp = textString;
            app.Label.Text = textString;  
        end

        % Value changed function: HeightEditField
        function HeightEditFieldValueChanged(app, event)
            value = app.HeightEditField.Value;
            app.hProp = value;
        end

        % Value changed function: WidthEditField
        function WidthEditFieldValueChanged(app, event)
            value = app.WidthEditField.Value;
            app.wProp = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EncodingTab = uitab(app.TabGroup);
            app.EncodingTab.Title = 'Encoding';
            app.EncoderLabel = uilabel(app.EncodingTab);
            app.EncoderLabel.Text = 'Encoder';
            app.TexttoEncodeEditFieldLabel = uilabel(app.EncodingTab);
            app.TexttoEncodeEditFieldLabel.Text = 'Text to Encode';
            app.TexttoEncodeEditField = uieditfield(app.EncodingTab, 'text');
            app.TexttoEncodeEditField.ValueChangedFcn = createCallbackFcn(app, @TexttoEncodeEditFieldValueChanged, true);
            app.TexttoEncodeEditField.Value = 'Sample Text';
            app.LoadImageButton = uibutton(app.EncodingTab, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.Image = uiimage(app.EncodingTab);
            app.Image2 = uiimage(app.EncodingTab);
            app.OriginalImageLabel = uilabel(app.EncodingTab);
            app.OriginalImageLabel.Text = 'Original Image';
            app.AlteredImageLabel = uilabel(app.EncodingTab);
            app.AlteredImageLabel.Text = 'Altered Image';
            app.Image3 = uiimage(app.EncodingTab);
            app.Image3.ImageSource = 'arrow.png';
            app.EncodeButton = uibutton(app.EncodingTab, 'push');
            app.EncodeButton.ButtonPushedFcn = createCallbackFcn(app, @EncodeButtonPushed, true);
            app.EncodeButton.Text = 'Encode';
            app.HeightEditFieldLabel = uilabel(app.EncodingTab);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.EncodingTab, 'numeric');
            app.HeightEditField.ValueChangedFcn = createCallbackFcn(app, @HeightEditFieldValueChanged, true);
            app.HeightEditField.Value = 256;
            app.WidthEditFieldLabel = uilabel(app.EncodingTab);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.EncodingTab, 'numeric');
            app.WidthEditField.ValueChangedFcn = createCallbackFcn(app, @WidthEditFieldValueChanged, true);
            app.WidthEditField.Value = 256;
            app.ResizeValuesLabel = uilabel(app.EncodingTab);
            app.ResizeValuesLabel.Text = 'Resize Values';
            app.DecodingTab = uitab(app.TabGroup);
            app.DecodingTab.Title = 'Decoding';
            app.DecoderLabel = uilabel(app.DecodingTab);
            app.DecoderLabel.Text = 'Decoder';
            app.LoadImageButton_2 = uibutton(app.DecodingTab, 'push');
            app.LoadImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButton_2Pushed, true);
            app.LoadImageButton_2.Text = 'Load Image';
            app.Image_2 = uiimage(app.DecodingTab);
            app.ImageLabel = uilabel(app.DecodingTab);
            app.ImageLabel.Text = 'Image';
            app.DecodedMessageLabel = uilabel(app.DecodingTab);
            app.DecodedMessageLabel.Text = 'Decoded Message:';
            app.Label = uilabel(app.DecodingTab);
            app.Label.Text = '';
            app.Image3_2 = uiimage(app.DecodingTab);
            app.Image3_2.ImageSource = 'arrow.png';
            app.DecodeButton = uibutton(app.DecodingTab, 'push');
            app.DecodeButton.ButtonPushedFcn = createCallbackFcn(app, @DecodeButtonPushed, true);
            app.DecodeButton.Text = 'Decode';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = proj

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end